classdef select_datapoints_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectdatapointsandbranchesPanel  matlab.ui.container.Panel
        XYButton                      matlab.ui.control.StateButton
        XZButton                      matlab.ui.control.StateButton
        YZButton                      matlab.ui.control.StateButton
        ViewLabel                     matlab.ui.control.Label
        Retry                         matlab.ui.control.Button
        ProximalbranchpointsarehighlightedflipifneccessaryLabel  matlab.ui.control.Label
        SelectusedBranchesincorrectorderLabel  matlab.ui.control.Label
        FlipLabel                     matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        FlipBranch1_4                 matlab.ui.control.CheckBox
        FlipBranch1_3                 matlab.ui.control.CheckBox
        FlipBranch1_2                 matlab.ui.control.CheckBox
        FlipBranch1                   matlab.ui.control.CheckBox
        ManuallyselectpointsCheckBox  matlab.ui.control.CheckBox
        DropDown_4                    matlab.ui.control.DropDown
        DropDown_3                    matlab.ui.control.DropDown
        DropDown_2                    matlab.ui.control.DropDown
        DropDown                      matlab.ui.control.DropDown
        OKButton                      matlab.ui.control.Button
        Axes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Callingapp % Description
        angiosegmentation
        vsegmentation
        branchList
        angio
        angiopixdim
        okpushed=0
        PWVpointList
        pixdim 
        v
        fig
        ax
    end
    
    methods (Access = private)

        
        function reset3DSegmentationAndCenterline(app)
            % Initialize figure
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            colorbar(app.Axes,'off')
            cla(app.Axes);
            if app.XYButton.Value || ~( app.XZButton.Value || app.YZButton.Value)
                dimorder=[1 2 3];
                xlabel(app.Axes,'X');
                ylabel(app.Axes,'Y');
            elseif app.XZButton.Value
                dimorder=[3 2 1];
                xlabel(app.Axes,'X');
                ylabel(app.Axes,'Z');
            elseif app.YZButton.Value
                dimorder=[3 1 2];
                xlabel(app.Axes,'Y');
                ylabel(app.Axes,'Z');
            end
            if app.Callingapp.LoadangioCheckBox.Value
                angio_perm = permute(app.angiosegmentation,dimorder);
                ss = smooth3(angio_perm);
                hpatch = patch(app.Axes,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(angio_perm);
                [x,y,z]=ind2sub(size(angio_perm),index);
                v_perm = permute(app.vsegmentation,dimorder);
                ss = smooth3(v_perm);
                hpatch1 = patch(app.Axes,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
                reducepatch(hpatch1 ,0.3);
                daspect(app.Axes,[1/app.angiopixdim(dimorder(2)) 1/app.angiopixdim(dimorder(1)) 1/app.angiopixdim(dimorder(3))])
            else
                v_perm = permute(app.vsegmentation,dimorder);
                ss = smooth3(v_perm);
                hpatch = patch(app.Axes,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(v_perm);
                [x,y,z]=ind2sub(size(v_perm),index);
                daspect(app.Axes,[1/app.pixdim(dimorder(2)) 1/app.pixdim(dimorder(1)) 1/app.pixdim(dimorder(3))])
            end
            m_xstart = min(x); m_ystart = min(y);m_zstart = min(z);
            m_xstop = max(x); m_ystop = max(y);m_zstop = max(z);
            xlim(app.Axes,[m_ystart-5 m_ystop+5]);
            ylim(app.Axes,[m_xstart-5 m_xstop+5]);
            zlim(app.Axes,[m_zstart-5 m_zstop+5]);
            axis(app.Axes, 'on')
            
            colormap(app.Axes,'colorcube')
            camlight(app.Axes);
            lighting(app.Axes,'gouraud');
            view(app.Axes, 2);
            unqBranches = unique(app.branchList(:,4));
            c = lines(length(unqBranches));
            % plot centerlines
            for b = 1:length(unqBranches)
                % extract coordinates for branch and plot
                currBranch = find(app.branchList(:,4) == b);
                hline(b) = line(app.Axes, ...
                    app.branchList(currBranch,dimorder(2)),app.branchList(currBranch,dimorder(1)),app.branchList(currBranch,dimorder(3)),...
                    'Color',c(b,:),'Marker','.','MarkerSize',12,'LineStyle','none');
            end
            c_bright=c.*1.3;
            tmp=c_bright>1;
            c_bright(tmp)=1;
            c_dark=c.*0.8;
            tmp=c_dark<0;
            c_dark(tmp)=0;
            % proximal point
            for b = 1:length(unqBranches)
                % extract coordinates for branch and plot
                currBranch = find(app.branchList(:,4) == b);
                hline2(b) = line(app.Axes,app.branchList(currBranch(1),dimorder(2)),app.branchList(currBranch(1),dimorder(1)),app.branchList(currBranch(1),dimorder(3)),...
                'Color',c_bright(b,:),'Marker','.','MarkerSize',24,'LineStyle','none');
            end
            % Put the number labels on the CenterlinePlot
            for i = 1:length(unqBranches)
                %find rows where textint = branchList(:,4) and take the mid point
                temp = app.branchList(app.branchList(:,4) == i,1:3);
                textLoc(i,1:3) = temp(round(size(temp,1)/2),:);
                Ntxt = text(app.Axes,textLoc(unqBranches(i),dimorder(2))+2,textLoc(unqBranches(i),dimorder(1))+2,textLoc(unqBranches(i),dimorder(3))-2,...
                num2str(unqBranches(i)),'Color',c_dark(i,:),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                'HitTest','off','PickableParts','none');
            end
            for i=1:size(app.PWVpointList,1)
                %roi=images.roi.Point(app.Axes,Color="black");
                %roi.Position=[app.PWVpointList(i,dimorder(2)) app.PWVpointList(i,dimorder(1))]; 
                Label="branch "+num2str(app.PWVpointList(i,4));
                hline2 = line(app.Axes,app.PWVpointList(i,dimorder(2)),app.PWVpointList(i,dimorder(1)),m_zstop,...
                    'Color','black','Marker','.','MarkerSize',18,'LineStyle','none');
                Ntxt = text(app.Axes,app.PWVpointList(i,dimorder(2))+2,app.PWVpointList(i,dimorder(1)),m_zstop,...
                    Label,'Color','white','BackgroundColor','black','FontSize',14,'FontWeight', 'bold','Margin', 1,...
                    'HitTest','off','PickableParts','none');
            end
            app.DropDown.Items = ['-'; string(unqBranches)];
            app.DropDown_2.Items = ['-'; string(unqBranches)];
            app.DropDown_3.Items = ['-'; string(unqBranches)];
            app.DropDown_4.Items = ['-'; string(unqBranches)];
            userStopped = false; 
            set(app.UIFigure,'Pointer','arrow');
            i=size(app.PWVpointList,1);
            while ~userStopped && app.ManuallyselectpointsCheckBox.Value 
                correct=0;
                roi=drawpoint(app.Axes,'Visible','off');
                if ~isvalid(roi) || isempty(roi.Position) || ~app.ManuallyselectpointsCheckBox.Value
                    % End the loop
                    userStopped = true;
                else
                    if isvalid(roi)
                        if ~(roi.Position(2)<=m_xstart-5) 
                            pause(0.3);
                            idx_cell=knnsearch([app.branchList(:,dimorder(2)) app.branchList(:,dimorder(1))],roi.Position,'IncludeTies',true);
                            idx=idx_cell{1};
                            branchpoint=1;
                            if length(idx)>1
                                while ~correct
                                    prompt = {'On which branch is this point?'};
                                    dlgtitle = 'Branch Nr.:';
                                    fieldsize = [1 45];
                                    definput = {num2str(app.branchList(idx(1),4))};
                                    answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                                    k=find(app.branchList(idx(:),4)==str2num(answer{1}),1,'first');
                                    if isempty(k)
                                        d=msgbox("I don't think so!");
                                        while isvalid(d);pause(0.1);end
                                    else
                                        correct=1;
                                        branchpoint=k;
                                    end
                                end
                            end
                            %roi.Position=[app.branchList(idx(branchpoint),dimorder(2)) app.branchList(idx(branchpoint),dimorder(1))]; 
                            [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(idx(branchpoint),1:3),'K',6);
                            if norm(dist(:,6))>9
                                d=msgbox("We do not have available velocity data for this point!");
                                while isvalid(d);pause(0.1);end
                                delete(roi);
                            else 
                                hline2 = line(app.Axes,app.branchList(idx(branchpoint),dimorder(2)),app.branchList(idx(branchpoint),dimorder(1)),m_zstop,...
                                'Color','black','Marker','.','MarkerSize',18,'LineStyle','none');
                                Label="branch "+num2str(app.branchList(idx(branchpoint),4));
                                Ntxt = text(app.Axes,app.branchList(idx(branchpoint),dimorder(2))+2,app.branchList(idx(branchpoint),dimorder(1)),m_zstop,...
                                Label,'Color','white','BackgroundColor','black','FontSize',14,'FontWeight', 'bold','Margin', 1,...
                                'HitTest','off','PickableParts','none');
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(idx(branchpoint),:);
                                %immediately plot waveform
                                if(1)
                                    if app.Callingapp.LoadangioCheckBox.Value
                                        [~,v_ind]=shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                                    else
                                        v_ind=app.PWVpointList(i,1:3);
                                    end
                                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                                    waveforms(i,:)=squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                                    if ~(exist('fig') || ishandle(7))
                                        app.fig=figure(7)
                                        app.ax=axes(app.fig);
                                    end
                                    %cla(ax,'reset')
                                    hold on
                                    plot(app.ax,app.Callingapp.delays(z_seg(neighbor_idx),:),waveforms(i,:))
                                    axis(app.ax,'tight')
                                    drawnow
                                end
                            end
                        else
                            delete(roi)
                        end
                    else
                        delete(roi);
                    end
                end 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, angiosegmentation, branchList, vsegmentation, angiopixdim, pixdim, v)
            app.Callingapp=caller;
            % initialize the mask
            app.angiosegmentation = angiosegmentation;
            app.vsegmentation=vsegmentation;
            app.branchList=branchList;
            app.angiopixdim=angiopixdim;
            app.pixdim=pixdim;
            app.v=v;
            app.XYButton.Value=app.Callingapp.XYButton.Value;
            app.XZButton.Value=app.Callingapp.XZButton.Value;
            app.YZButton.Value=app.Callingapp.YZButton.Value;
            reset3DSegmentationAndCenterline(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            branches=zeros(4,1);
            choice=0;
            if ~strcmp(app.DropDown.Value,'-')
                branches(1)=str2num(app.DropDown.Value);
            else
                e=errordlg("Select at least one branch!");
                uiwait(e);
                choice=1;
            end
            if ~strcmp(app.DropDown_2.Value,'-')
                branches(2)=str2num(app.DropDown_2.Value);
            end
            if ~strcmp(app.DropDown_3.Value,'-')
                branches(3)=str2num(app.DropDown_3.Value);
            end
            if ~strcmp(app.DropDown_4.Value,'-')
                branches(4)=str2num(app.DropDown_4.Value);
            end
            if ~app.ManuallyselectpointsCheckBox.Value
                app.PWVpointList=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end    
            end
            if ~isempty(app.PWVpointList)
                comp=ismember(app.PWVpointList(:,4),branches(branches>0));
            else
                comp=1;
            end
            if size(app.PWVpointList,1)<3
                choice=choosedialog;
            elseif sum(comp==0)>0
                e=errordlg("Do not pick points from branches you did not select!");
                uiwait(e);
                choice=1;
            end
            if choice
                reset3DSegmentationAndCenterline(app);
            else
                idx=branches>0;
                branches_real=branches(idx);
                app.Callingapp.PWVpointList=app.PWVpointList;
                app.Callingapp.flip1=app.FlipBranch1.Value;
                app.Callingapp.flip2=app.FlipBranch1_2.Value;
                app.Callingapp.flip3=app.FlipBranch1_3.Value;
                app.Callingapp.flip4=app.FlipBranch1_4.Value;
                app.Callingapp.branches=branches_real;
                try
                    close(app.fig)
                catch
                end
                delete(app);
            end
        end

        % Value changed function: ManuallyselectpointsCheckBox
        function ManuallyselectpointsCheckBoxValueChanged(app, event)
            app.PWVpointList=[];
            %ud = findobj('Type','images.roi.Point');
            %delete(ud);
            reset3DSegmentationAndCenterline(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if ~strcmp(value,'-')
                app.DropDown_2.Visible="on";
                app.FlipBranch1_2.Visible="on";
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if ~strcmp(value,'-')
                app.DropDown_3.Visible="on";
                app.FlipBranch1_3.Visible="on";
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if ~strcmp(value,'-')
                app.DropDown_4.Visible="on";
                app.FlipBranch1_4.Visible="on";
            end
        end

        % Button pushed function: Retry
        function RetryButtonPushed(app, event)
            app.PWVpointList=[];
            %ud = findobj('Type','images.roi.Point');
            %delete(ud);
            close(app.fig)
            reset3DSegmentationAndCenterline(app);
        end

        % Value changed function: XYButton
        function XYButtonValueChanged(app, event)
            value = app.XYButton.Value;
            if value==0
                app.XYButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end

        % Value changed function: XZButton
        function XZButtonValueChanged(app, event)
            value = app.XZButton.Value;
            if value==0
                app.XZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.YZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end

        % Value changed function: YZButton
        function YZButtonValueChanged(app, event)
            value = app.YZButton.Value;
            if value==0
                app.YZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.XZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectdatapointsandbranchesPanel = uipanel(app.UIFigure);
            app.SelectdatapointsandbranchesPanel.Title = 'Select datapoints and branches';
            app.Axes = uiaxes(app.SelectdatapointsandbranchesPanel);
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.OKButton = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DropDown = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown.Items = {'-'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '-';
            app.DropDown_2 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_2.Items = {'-'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = '-';
            app.DropDown_3 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_3.Items = {'-'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = '-';
            app.DropDown_4 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_4.Items = {'-'};
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Value = '-';
            app.ManuallyselectpointsCheckBox = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.ManuallyselectpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallyselectpointsCheckBoxValueChanged, true);
            app.ManuallyselectpointsCheckBox.Text = 'Manually select points';
            app.ManuallyselectpointsCheckBox.Value = true;
            app.FlipBranch1 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1.Tooltip = {''};
            app.FlipBranch1.Text = '';
            app.FlipBranch1_2 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_2.Visible = 'off';
            app.FlipBranch1_2.Tooltip = {''};
            app.FlipBranch1_2.Text = '';
            app.FlipBranch1_3 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_3.Visible = 'off';
            app.FlipBranch1_3.Tooltip = {''};
            app.FlipBranch1_3.Text = '';
            app.FlipBranch1_4 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_4.Visible = 'off';
            app.FlipBranch1_4.Tooltip = {''};
            app.FlipBranch1_4.Text = '';
            app.Button = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'infoicon.jpg');
            app.Button.Enable = 'off';
            app.Button.Tooltip = {'If not checked, every available point is automatically chosen on the picked branches'};
            app.Button.Text = '';
            app.FlipLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.FlipLabel.Text = 'Flip:';
            app.SelectusedBranchesincorrectorderLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.SelectusedBranchesincorrectorderLabel.Text = 'Select used Branches in correct order:';
            app.ProximalbranchpointsarehighlightedflipifneccessaryLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.ProximalbranchpointsarehighlightedflipifneccessaryLabel.Text = 'Proximal branch points are highlighted: flip if neccessary';
            app.Retry = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.Retry.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.Retry.Text = 'Retry';
            app.ViewLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.ViewLabel.Text = 'View:';
            app.YZButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.YZButton.ValueChangedFcn = createCallbackFcn(app, @YZButtonValueChanged, true);
            app.YZButton.Text = 'YZ';
            app.XZButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.XZButton.ValueChangedFcn = createCallbackFcn(app, @XZButtonValueChanged, true);
            app.XZButton.Text = 'XZ';
            app.XYButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.XYButton.ValueChangedFcn = createCallbackFcn(app, @XYButtonValueChanged, true);
            app.XYButton.Text = 'XY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_datapoints_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end